classdef appdesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ConditionStatus          matlab.ui.control.EditField
        ConditionEditFieldLabel  matlab.ui.control.Label
        Convert                  matlab.ui.control.Button
        Connect                  matlab.ui.control.Button
        Start                    matlab.ui.control.Button
        RobotDog                 matlab.ui.control.Label
        th3deg                   matlab.ui.control.NumericEditField
        th3degEditFieldLabel     matlab.ui.control.Label
        th1deg                   matlab.ui.control.NumericEditField
        th1degEditFieldLabel     matlab.ui.control.Label
        th2deg                   matlab.ui.control.NumericEditField
        th2degEditFieldLabel     matlab.ui.control.Label
        Zmm                      matlab.ui.control.NumericEditField
        ZmmEditFieldLabel        matlab.ui.control.Label
        Ymm                      matlab.ui.control.NumericEditField
        YmmEditFieldLabel        matlab.ui.control.Label
        Xmm                      matlab.ui.control.NumericEditField
        XmmEditFieldLabel        matlab.ui.control.Label
        display                  matlab.ui.control.Button
        SliderZ                  matlab.ui.control.Slider
        SliderY                  matlab.ui.control.Slider
        SliderX                  matlab.ui.control.Slider
        UIAxesKN                 matlab.ui.control.UIAxes
        UIAxesWP                 matlab.ui.control.UIAxes
    end

    properties (Access = public)
        R=70.5;
        r=35.03;
        L=110.49;
        l=457.95;
        angle = linspace(0, pi/2, 25);
        theta = linspace(0, 2 * pi, 100);
        condition = false;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SliderX
        function SliderXValueChanged(app, event)
            app.Xmm.Value = app.SliderX.Value;
        end

        % Value changed function: SliderY
        function SliderYValueChanged(app, event)
            app.Ymm.Value = app.SliderY.Value;
        end

        % Value changed function: SliderZ
        function SliderZValueChanged(app, event)
            app.Zmm.Value = app.SliderZ.Value;
        end

        % Button pushed function: display
        function displayPushed(app, event)
            if app.condition
                plotKinematics(app);
                app.display.BackgroundColor = 'red';
                app.ConditionStatus.Value = "perpect work space";
            else
                app.ConditionStatus.Value = "none of work space";
            end
        end

        % Button pushed function: Start
        function StartButtonPushed(app, event)
            app.condition = true;
            plotWorkspace(app);
        end

        % Button pushed function: Connect
        function ConnectButtonPushed(app, event)
            disp("Button Pressed: Connecting to Serial...");
            serialComApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesWP = uiaxes(app.UIFigure);
            xlabel(app.UIAxesWP, 'X')
            ylabel(app.UIAxesWP, 'Y')
            zlabel(app.UIAxesWP, 'Z')
            app.UIAxesWP.LineStyleOrder = {'-'};
            app.UIAxesKN = uiaxes(app.UIFigure);
            xlabel(app.UIAxesKN, 'X')
            ylabel(app.UIAxesKN, 'Y')
            zlabel(app.UIAxesKN, 'Z')
            app.SliderX = uislider(app.UIFigure);
            app.SliderX.Limits = [-100 100];
            app.SliderX.ValueChangedFcn = createCallbackFcn(app, @SliderXValueChanged, true);
            app.SliderX.Value = 10;
            app.SliderY = uislider(app.UIFigure);
            app.SliderY.Limits = [-100 100];
            app.SliderY.ValueChangedFcn = createCallbackFcn(app, @SliderYValueChanged, true);
            app.SliderY.Value = 10;
            app.SliderZ = uislider(app.UIFigure);
            app.SliderZ.Limits = [100 400];
            app.SliderZ.ValueChangedFcn = createCallbackFcn(app, @SliderZValueChanged, true);
            app.SliderZ.Value = 100;
            app.display = uibutton(app.UIFigure, 'push');
            app.display.ButtonPushedFcn = createCallbackFcn(app, @displayPushed, true);
            app.display.Icon = fullfile(pathToMLAPP, 'image', 'play.png');
            app.display.Text = '';
            app.XmmEditFieldLabel = uilabel(app.UIFigure);
            app.XmmEditFieldLabel.Text = 'X (mm)';
            app.Xmm = uieditfield(app.UIFigure, 'numeric');
            app.YmmEditFieldLabel = uilabel(app.UIFigure);
            app.YmmEditFieldLabel.Text = 'Y (mm)';
            app.Ymm = uieditfield(app.UIFigure, 'numeric');
            app.ZmmEditFieldLabel = uilabel(app.UIFigure);
            app.ZmmEditFieldLabel.Text = 'Z (mm)';
            app.Zmm = uieditfield(app.UIFigure, 'numeric');
            app.th2degEditFieldLabel = uilabel(app.UIFigure);
            app.th2degEditFieldLabel.Text = 'th2 (deg)';
            app.th2deg = uieditfield(app.UIFigure, 'numeric');
            app.th1degEditFieldLabel = uilabel(app.UIFigure);
            app.th1degEditFieldLabel.Text = 'th1 (deg)';
            app.th1deg = uieditfield(app.UIFigure, 'numeric');
            app.th3degEditFieldLabel = uilabel(app.UIFigure);
            app.th3degEditFieldLabel.Text = 'th3 (deg)';
            app.th3deg = uieditfield(app.UIFigure, 'numeric');
            app.RobotDog = uilabel(app.UIFigure);
            app.RobotDog.Text = 'RobotDog';
            app.Start = uibutton(app.UIFigure, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.Start.Icon = fullfile(pathToMLAPP, 'image', 'power-switch.png');
            app.Start.Text = '';
            app.Connect = uibutton(app.UIFigure, 'push');
            app.Connect.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.Connect.Icon = fullfile(pathToMLAPP, 'image', 'link.png');
            app.Connect.Text = '';
            app.Convert = uibutton(app.UIFigure, 'push');
            app.Convert.Icon = fullfile(pathToMLAPP, 'image', 'rotation.png');
            app.Convert.Text = '';
            app.ConditionEditFieldLabel = uilabel(app.UIFigure);
            app.ConditionEditFieldLabel.Text = 'Condition';
            app.ConditionStatus = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appdesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end